classdef Lab_3_logic_app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UpperRadiusBoundEditField       matlab.ui.control.NumericEditField
        UpperRadiusBoundEditFieldLabel  matlab.ui.control.Label
        LowerRadiusBoundEditField       matlab.ui.control.NumericEditField
        LowerRadiusBoundEditFieldLabel  matlab.ui.control.Label
        FindCircleButton                matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FindCircleButton
        function FindCircleButtonPushed(app, event)
cols = 752;
rows = 480;

w = 100;
h = 100;
colormap gray
            %fold this logic into the lab two app to highlight the centroid
            %add in a ui get element line
            %adding in a subplot feature is good for visualizing the image
            %data path which will come up later
            Frame_1 = imread("left9.jpg");
            formated_frame_1 = rgb2gray(Frame_1);
            imagesc(formated_frame_1)

            binary = zeros(rows,cols);
            for x = 1:cols
                for y = 1:rows
                    if (formated_frame_1(y,x) > 150)
                        binary(y,x) = 1;
                    end
                end
            end
            imshow(formated_frame_1)
            [center, radius] = imfindcircles(formated_frame_1,[app.LowerRadiusBoundEditField.Value app.UpperRadiusBoundEditField.Value])
            viscircles(center, radius, 'Color', 'red')


            %fold this logic into the lab two app to highlight the centroid
            %add in a ui get element line
            Frame_2 = imread("right9.jpg");
            formated_frame_2 = rgb2gray(Frame_2);
            imagesc(formated_frame_2)

            binary = zeros(rows,cols);
            for x = 1:cols
                for y = 1:rows
                    if (formated_frame_2(y,x) > 150)
                        binary(y,x) = 1;
                    end
                end
            end
            imshow(formated_frame_2)
            [center, radius] = imfindcircles(formated_frame_2,[app.LowerRadiusBoundEditField.Value app.UpperRadiusBoundEditField.Value])
            viscircles(center, radius, 'Color', 'red')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.FindCircleButton = uibutton(app.UIFigure, 'push');
            app.FindCircleButton.ButtonPushedFcn = createCallbackFcn(app, @FindCircleButtonPushed, true);
            app.FindCircleButton.Text = 'Find Circle';
            app.LowerRadiusBoundEditFieldLabel = uilabel(app.UIFigure);
            app.LowerRadiusBoundEditFieldLabel.Text = 'Lower Radius Bound';
            app.LowerRadiusBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperRadiusBoundEditFieldLabel = uilabel(app.UIFigure);
            app.UpperRadiusBoundEditFieldLabel.Text = 'Upper Radius Bound';
            app.UpperRadiusBoundEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab_3_logic_app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end